classdef index < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UploadVideosButton        matlab.ui.control.Button
        AnalyzeResultsButton      matlab.ui.control.Button
        RemoveFilesPanel          matlab.ui.container.Panel
        RemoveSelectedFileButton  matlab.ui.control.Button
        DropDownLabel             matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        Panel                     matlab.ui.container.Panel
        AreyousureLabel           matlab.ui.control.Label
        yesButton                 matlab.ui.control.Button
        noButton                  matlab.ui.control.Button
        CloseAppButton            matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        UITable2                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        videoList % List of video with file paths
        files % List of files
        paths % List of file paths
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CloseAppButton
        function CloseAppButtonPushed(app, event)
            % Close the app
            delete(app);
        end

        % Button pushed function: AnalyzeResultsButton
        function AnalyzeResultsButtonPushed(app, event)
            % Reorganize filepaths of videos and pass to second page
            dimensions = size(app.videoList);
            newArray = [];
            for k=1:dimensions(1)
                newArray = [newArray strcat(app.videoList(k,2), app.videoList(k,1))];
            end
            visualizeResults(app, newArray);
        end

        % Button pushed function: UploadVideosButton
        function UploadVideosButtonPushed(app, event)
            % Prompt user to select videos
            [file,path,~] = uigetfile( ...
            {'*.avi','AVI (*.avi)'; ...
            '*.webm','WEBM (*.webm)'; ...
                '*.mp4;*.m4p;*.m4v','MP4 (*.mp4, *.m4p, *.m4v)'; ...
                '*.mpg;*.mp2;*.mpeg;*.mpe;*.mpv','MPG (*.mpg, *.mp2, *.mpeg, *.mpe, *.mpv)'; ...
                '*.ogg','OGG (*.ogg)'; ...
                '*.wmv','WMV (*.wmv)'; ...
                '*.mov;*.qt','QuickTime (*.mov, *.qt)'; ...
                '*.flv;*.swf','Flash (*.flv, *.swf)'}, ...
                'Select Multiple Files by Holding Shift or Ctrl', 'MultiSelect', 'on');
            
            % If there is no file, do nothing
            if isequal(file,0)
                
            % Else, 
            else
                % If it is a single file, add it accordingly (its an array)
                if(~iscell(file))
                    app.videoList = [app.videoList; string(file), string(path)];
                % If there is more than one file, add it respectively (its
                % a cell array)
                else
                    for k=1:length(file)
                        app.videoList = [app.videoList; string(file(k)), string(path)];
                    end
                end
            end
            
            % If there's nothing in the stored list of videos, initialize
            % the visible elements to nothing
            if(isempty(app.videoList))
                app.files = [];
                app.paths = [];
                app.DropDown.Items = {};
                
            % Else show the list in the different visible elements
            else
                app.files = app.videoList(:,1);
                app.paths = app.videoList(:,2);
                
                % Changes the values in the drop down
                app.DropDown.Items = app.files;
            end
            
            % Changes the value in the table
            app.UITable.Data = app.files;
            app.UITable2.Data = app.paths;
          
        end

        % Button pushed function: RemoveSelectedFileButton
        function RemoveSelectedFileButtonPushed(app, event)
            % If there is a selection in the dropdown, open up panel
            if(isempty(app.DropDown.Value))
            else
                app.Panel.Visible = 'on';
            end
        end

        % Button pushed function: noButton
        function noButtonPushed(app, event)
            % Turn the panel off
            app.Panel.Visible = 'off';
        end

        % Button pushed function: yesButton
        function yesButtonPushed(app, event)
            % Finds the index of the selected item
            dimensions = size(app.videoList);
            for i= 1:dimensions(1)
                if (app.videoList(i,1) == app.DropDown.Value)
                    app.videoList(i,:) = [];
                    break
                end
            end
            
            app.files = app.videoList(:,1);
            app.paths = app.videoList(:,2);
            
            % Changes the value in the table
            app.UITable.Data = app.files;
            app.UITable2.Data = app.paths;
                
            % Changes the values in the drop down
            app.DropDown.Items = app.files;
            
            app.Panel.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UploadVideosButton = uibutton(app.UIFigure, 'push');
            app.UploadVideosButton.ButtonPushedFcn = createCallbackFcn(app, @UploadVideosButtonPushed, true);
            app.UploadVideosButton.Text = 'Upload Video(s)';
            app.AnalyzeResultsButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeResultsButtonPushed, true);
            app.AnalyzeResultsButton.Text = 'Analyze Results';
            app.RemoveFilesPanel = uipanel(app.UIFigure);
            app.RemoveFilesPanel.Title = 'Remove Files';
            app.RemoveSelectedFileButton = uibutton(app.RemoveFilesPanel, 'push');
            app.RemoveSelectedFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedFileButtonPushed, true);
            app.RemoveSelectedFileButton.Text = 'Remove Selected File';
            app.DropDownLabel = uilabel(app.RemoveFilesPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.RemoveFilesPanel);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.AreyousureLabel = uilabel(app.Panel);
            app.AreyousureLabel.Text = 'Are you sure?';
            app.yesButton = uibutton(app.Panel, 'push');
            app.yesButton.ButtonPushedFcn = createCallbackFcn(app, @yesButtonPushed, true);
            app.yesButton.Text = 'yes';
            app.noButton = uibutton(app.Panel, 'push');
            app.noButton.ButtonPushedFcn = createCallbackFcn(app, @noButtonPushed, true);
            app.noButton.Text = 'no';
            app.CloseAppButton = uibutton(app.UIFigure, 'push');
            app.CloseAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAppButtonPushed, true);
            app.CloseAppButton.Text = 'Close App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File Name'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'File Path'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = index

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end