classdef visualizeResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        PleasewaitLabel         matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        UITable_2               matlab.ui.control.Table
        FinalEmbryoSizeLabel    matlab.ui.control.Label
        AverageGrowthRateLabel  matlab.ui.control.Label
        Button                  matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        UITable2                matlab.ui.control.Table
        Panel                   matlab.ui.container.Panel
        SaveresultsButton       matlab.ui.control.Button
        LoadnewvideosButton     matlab.ui.control.Button
        SaveImagesLabelsButton  matlab.ui.control.Button
        CloseAppButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        file % List of files [file file file]
        finalResult % Output information
        storePixels % {(empty) (video pixel array) (video pixel array) ...}
        storeAGRRank % Stores the average growth rate ranks for table [name, rank; name rank;]
        storeFinalSizeRank % Stores the final size ranking for the table [name, rank; name rank;]
        storeRegress % Stores the linear regression of each video
        storeAll % Stores all videos in a struct with name, final size, final size rank, growth, growth rank, and pixels
        storeGraph % Index for the first table
        storeGraph2 % Index for the second table 
        current % Current directory path
        storeTime % Stores the time from each video in an cell array
        saveImage % A flag to see if the user wants the images to be saved
        f % Loading bar
    end
    
    methods (Access = private)
        
        function output(app)
            % Create a folder for the images and labels
            mkdir('Images & Labels');
            
            % Change to the created directory
            cd('Images & Labels');
            
            % Initialize variables
            output = ["Videos","Time (h)","Initial Size (um^2)","Final Size (um^2)","Final Size Rank","Average Growth Rate","Avg Growth Rate Rank"];
            imageSize = 496;
            pixel2real = 0.58*0.58;
            
            % Load network
            load('net_all_combined_all.mat', 'net');
            
            fileSize = size(app.file);
            numVideos = fileSize(2);
            rankGrowth = zeros(numVideos, 2);
            rankSize = zeros(numVideos, 2);
            for i=1:numVideos
                rankGrowth(i) = i;
                rankSize(i) = i;
            end
            
            % Process each video
            for i=1:numVideos
                % Update waiting bar
                waitbar(i/numVideos, app.f, strcat("Processing Video ", num2str(i), " of ", num2str(numVideos)));
                files = app.file(i);

                % Get the part of the file
                [filepath,name,~] = fileparts(files);
                
                % Create a folder for this video
                mkdir(name);
                
                % Create a folder for the labels
                mkdir(strcat(name, '_new'));
                
                % Get the number of frames for each video
                vidObj = VideoReader(files);
                time_to_remember = vidObj.CurrentTime;
                numFrames = 0;
                while hasFrame(vidObj)
                    readFrame(vidObj);
                    numFrames = numFrames + 1;
                end
                pixelArray = zeros(1,numFrames);
                timeArray = zeros(1,numFrames);
                vidObj.CurrentTime = time_to_remember;
                
                % For each frame
                for k=1:numFrames
                    
                    % Process the images to gray and resize (496 496)
                    Image = readFrame(vidObj);
                    Image = rgb2gray(Image);
                    Image = imresize(Image, [imageSize imageSize]);
                    
                    % Calculate the time using the timestamps in the images
                    timeArray(k) = timeCalc(Image);
                    
                    % Create the filename for the frame
                    if(k<10)
                        filename=strcat(name,'/','00',num2str(k),'_',name,'.png');
                    elseif(k>=10 && k<=99)
                        filename=strcat(name,'/','0',num2str(k),'_',name,'.png');    
                    else
                        filename=strcat(name,'/',num2str(k),'_',name,'.png');
                    end
                    strcat(filepath,'/',name,'/',filename);
                    
                    % Create image
                    imwrite(Image,filename,'png');
                        
                    % Predict the embryo using the loaded network
                    [C,~] = semanticseg(Image, net);
                    
                    % Find the number of pixels in the predicted image
                    pixelArray(k) = sum(C(:) == "Embryo")*pixel2real;
                    Image = labeloverlay(Image, C);
                    
                    % Create the filename for the label
                    if(k<10)
                        filename=strcat(name,'_new','/','00',num2str(k),'_',name,'.png');
                    elseif(k>=10 && k<=99)
                        filename=strcat(name,'_new','/','0',num2str(k),'_',name,'.png');    
                    else
                        filename=strcat(name,'_new','/',num2str(k),'_',name,'.png');
                    end
                    strcat(filepath,'_new','/',name,'/',filename);
                    
                    % Create label w/ image
                    imwrite(Image,filename,'png');
                end
            
                % Normalize time
                baseTime = timeArray(1);
                for k=1:numFrames
                   timeArray(k) = timeArray(k) - baseTime; 
                end
                
                % Store variables
                rankSize(i,2) = pixelArray(numFrames);
                app.storePixels{i} = pixelArray;
                P = linearRegression(pixelArray, timeArray);
                app.storeTime{i} = timeArray;
                app.storeRegress = [app.storeRegress; P];
                rankGrowth(i,2) = P(1);
                name = convertCharsToStrings(name);
                array = [name timeArray(numFrames) pixelArray(1) pixelArray(numFrames) 1 rankGrowth(i,2) 1];
                output = [output; array];
            end

            cd(app.current);
            
            % Sort the videos for ranking using insertion sort
            if(numVideos ~= 1)
                for i=2:numVideos
                    key = rankSize(i, 2);
                    m = i - 1;
                
                    while(m >= 1 && rankSize(m, 2) > key)
                        rankSize(m+1, 2) = rankSize(m, 2);
                        rankSize(m+1, 1) = rankSize(m, 1);
                        m = m - 1;
                    end
                    rankSize(m+1, 2) = key;
                    rankSize(m+1, 1) = i;
                end
                for i=2:numVideos
                    key = rankGrowth(i, 2);
                    m = i - 1;
                
                    while(m >= 1 && rankGrowth(m, 2) > key)
                        rankGrowth(m+1, 2) = rankGrowth(m, 2);
                        rankGrowth(m+1, 1) = rankGrowth(m, 1);
                        m = m - 1;
                    end
                    rankGrowth(m+1, 2) = key;
                    rankGrowth(m+1, 1) = i;
                end
                for i=1:numVideos
                    output(1+rankSize(i), 5) = numVideos + 1 - i;
                    output(1+rankGrowth(i), 7) = numVideos + 1 - i;
                end
            end
            
            % Store the final size and growth rate rank as internal
            % variables
            for i=1:numVideos
                app.storeFinalSizeRank = [app.storeFinalSizeRank; output(1+i, 1) output(1+i, 5) output(1+i,4)];
                app.storeAGRRank = [app.storeAGRRank; output(1+i, 1) output(1+i, 7) output(1+i,6)];
            end
            
            % Store the final results table in a variable for later
            app.finalResult = output;

            % Close the loading bar
            close(app.f);
            
            % Update label to indicate status
            app.PleasewaitLabel.Text = 'Predictions Done.';
            drawnow
        end
    end
    
    methods (Access = public)
        
        function sortall(app)
            numVideos = size(app.finalResult(:,1));
            
            % For all videos, sort the rankings for app storage
            for i=1:numVideos(1)-1
                for j=1:numVideos(1)-1
                    if(str2double(app.storeFinalSizeRank(i,2)) == j)
                        temp = app.storeFinalSizeRank(j,:);
                        app.storeFinalSizeRank(j,:) = app.storeFinalSizeRank(i,:);
                        app.storeFinalSizeRank(i,:) = temp;
                    end
                    if(str2double(app.storeAGRRank(i,2)) == j)
                        temp = app.storeAGRRank(j,:);
                        app.storeAGRRank(j,:) = app.storeAGRRank(i,:);
                        app.storeAGRRank(i,:) = temp;
                    end
                end
            end
            
            % For all videos, create a struct that stores final size,
            % growth, all rankings, pixels, and time
            for k=1:numVideos(1)-1
                video.name = app.finalResult(k+1,1);
                for m=1:numVideos(1)-1
                    if(video.name == app.storeFinalSizeRank(m,1))
                        video.sizeRank = app.storeFinalSizeRank(m,2);
                        video.size = app.storeFinalSizeRank(m,3);
                    end
                    if(video.name == app.storeAGRRank(m,1))
                        video.growthRank = app.storeAGRRank(m,2);
                    end
                end
                video.pixel = app.storePixels{k};
                video.regress = app.storeRegress(k,:);
                video.time = app.storeTime{k};
                app.storeAll = [app.storeAll; video];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app2, files)
            % Create a loading bar
            app.f = waitbar(0, 'Loading Videos...');
            
            % Store the filepaths to the videos
            app.file = files;
            
            % Store the current directory
            app.current = pwd;
            
            % Delete the first page
            delete(app2);
            
            % Analyze the embryos
            output(app);
            
            % Sort the rankings
            sortall(app);
            
            % Update the graph with the first video
            plot(app.UIAxes,app.storeAll(1).pixel,'-r');
            hold(app.UIAxes,'on');
            
            % Plot the linear regression
            x = app.storeTime{1};
            plot(app.UIAxes,app.storeAll(1).regress(1,1)*x+app.storeAll(1).regress(1,2),'--b');
            hold(app.UIAxes, 'off');

            % Update both tables
            table = [app.storeAll(1).name app.storeAll(1).size app.storeAll(1).regress(1,1)];
            app.UITable2.Data = table;
            app.UITable.Data = app.storeFinalSizeRank;
            app.UITable_2.Data = app.storeAGRRank;
            
            % Update the index for the first table
            app.storeGraph = 1;
            
            % Create a style for the tables
            s = uistyle;
            s.BackgroundColor = 'y';
            
            % Highlight the selected video with yellow
            addStyle(app.UITable, s, 'row', app.storeGraph);
            numVideos = size(app.finalResult(:,1));
            
            % Find the index on the second table
            for k=1:numVideos(1)-1
                if(app.storeFinalSizeRank(app.storeGraph,1) == app.storeAGRRank(k,1))
                    app.storeGraph2 = k;
                    
                    % Highlight row with yellow to indicate selection
                    addStyle(app.UITable_2, s, 'row', app.storeGraph2);
                end
            end
            
            % Make more buttons visible
            app.SaveImagesLabelsButton.Visible = 'on';
            app.SaveresultsButton.Visible = 'on';
            
            % Update label to reflect status
            app.PleasewaitLabel.Text = 'Ready.';
        end

        % Button pushed function: LoadnewvideosButton
        function LoadnewvideosButtonPushed(app, event)
            drawnow; 
            close all force;
            if(~ishandle(app.f))
                close(app.f);
            end
            
            % If user didn't save images delete them
            if(app.saveImage ~= 1)
                rmdir('Images & Labels', 's');
            end
            
            % Open up the first page
            index;
            
            % Delete current page
            delete(app);
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            % Let the user select where they want to save the info
            path = uigetdir;
            
            % Change directory
            cd(path);
            
            % Save the file
            writematrix(app.finalResult, 'embryo_results.csv');
            
            % Return to the current directory
            cd(app.current);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % Get selected row
            row = event.Indices(1);
            
            % Create a style
            s = uistyle;
            
            % Turn all highlights to white in both tables            
            s.BackgroundColor = 'w';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            addStyle(app.UITable_2, s, 'row', app.storeGraph2);
            
            % Update first table index
            app.storeGraph = row;
            
            % Make the highlight yellow and add it to the selected row
            s.BackgroundColor = 'y';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            
            % Find the corresponding struct to find the index equivalent of
            % the video in the second table
            numVideos = size(app.finalResult(:,1));
            for k=1:numVideos(1)-1
                if(app.storeFinalSizeRank(row,1) == app.storeAll(k).name)
                    
                    % Change plot and highlight the correct row in the
                    % second table
                    plot(app.UIAxes,app.storeAll(k).pixel,'-r');
                    hold(app.UIAxes,'on');
                    x = app.storeAll(k).time;
                    plot(app.UIAxes,app.storeAll(k).regress(1,1)*x+app.storeAll(k).regress(1,2),'--b');
                    hold(app.UIAxes, 'off');
                    app.storeGraph2 = str2double(app.storeAll(k).growthRank);
                    addStyle(app.UITable_2, s, 'row', app.storeGraph2);
                    
                    % Update information on the table below the graph
                    table = [app.storeAll(k).name app.storeAll(k).size app.storeAll(k).regress(1,1)];
                    app.UITable2.Data = table;
                end
            end
        end

        % Cell selection callback: UITable_2
        function UITable_2CellSelection(app, event)
            % Get selected row
            row = event.Indices(1);
            
            % Create a style
            s = uistyle;
            
            % Turn all highlights to white in both tables            
            s.BackgroundColor = 'w';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            addStyle(app.UITable_2, s, 'row', app.storeGraph2);
            
            % Update second table index
            app.storeGraph2 = row;
            
            % Make the highlight yellow and add it to the selected row
            s.BackgroundColor = 'y';
            addStyle(app.UITable_2, s, 'row', app.storeGraph2);
            
            % Find the corresponding struct to find the index equivalent of
            % the video in the first table
            numVideos = size(app.finalResult(:,1));
            for k=1:numVideos(1)-1
                if(app.storeAGRRank(row,1) == app.storeAll(k).name)
                    
                    % Change plot and highlight the correct row in the
                    % second table
                    plot(app.UIAxes,app.storeAll(k).pixel,'-r');
                    hold(app.UIAxes,'on');
                    x = app.storeAll(k).time;
                    plot(app.UIAxes,app.storeAll(k).regress(1,1)*x+app.storeAll(k).regress(1,2),'--b');
                    hold(app.UIAxes, 'off');
                    app.storeGraph = str2double(app.storeAll(k).sizeRank);
                    addStyle(app.UITable, s, 'row', app.storeGraph);
                    
                    % Update information on the table below the graph
                    table = [app.storeAll(k).name app.storeAll(k).size app.storeAll(k).regress(1,1)];
                    app.UITable2.Data = table;
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Create a highlight style for the tables
            s = uistyle;
            s.BackgroundColor = 'w';
            
            % Turn all highlights to white in both tables
            addStyle(app.UITable, s, 'row', app.storeGraph);
            addStyle(app.UITable_2, s, 'row', app.storeGraph2);
            numVideos = size(app.finalResult(:,1));
            
            % Increase the index for table 1
            app.storeGraph = app.storeGraph + 1;
            
            % If the index would become less than the number of videos,
            % cycle back
            if(app.storeGraph > numVideos(1)-1)
                app.storeGraph = 1;
            end
            
            % Make the highlight yellow and add it to the selected row
            s.BackgroundColor = 'y';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            
            % Find the corresponding struct to find the index equivalent of
            % the video in the second table
            for k=1:numVideos(1)-1
                if(app.storeFinalSizeRank(app.storeGraph,1) == app.storeAll(k).name)
                    
                    % Change plot and highlight the correct row in the
                    % second table
                    plot(app.UIAxes,app.storeAll(k).pixel,'-r');
                    hold(app.UIAxes,'on');
                    x = app.storeAll(k).time;
                    plot(app.UIAxes,app.storeAll(k).regress(1,1)*x+app.storeAll(k).regress(1,2),'--b');
                    hold(app.UIAxes, 'off');
                    app.storeGraph2 = str2double(app.storeAll(k).growthRank);
                    addStyle(app.UITable_2, s, 'row', app.storeGraph2);
                    
                    % Update information on the table below the graph
                    table = [app.storeAll(k).name app.storeAll(k).size app.storeAll(k).regress(1,1)];
                    app.UITable2.Data = table;
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Create a highlight style for the tables
            s = uistyle;
            s.BackgroundColor = 'w';
            
            % Turn all highlights to white in both tables
            addStyle(app.UITable, s, 'row', app.storeGraph);
            addStyle(app.UITable_2, s, 'row', app.storeGraph2);
            numVideos = size(app.finalResult(:,1));
            
            % Decrease the index for table 1
            app.storeGraph = app.storeGraph - 1;
            
            % If the index would become less than the number of videos,
            % cycle back
            if(app.storeGraph < 1)
                app.storeGraph = numVideos(1)-1;
            end            

            % Make the highlight yellow and add it to the selected row
            s.BackgroundColor = 'y';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            
            % Find the corresponding struct to find the index equivalent of
            % the video in the second table
            for k=1:numVideos(1)-1
                if(app.storeFinalSizeRank(app.storeGraph,1) == app.storeAll(k).name)
                    
                    % Change plot and highlight the correct row in the
                    % second table
                    plot(app.UIAxes,app.storeAll(k).pixel,'-r');
                    hold(app.UIAxes,'on');
                    x = app.storeAll(k).time;
                    plot(app.UIAxes,app.storeAll(k).regress(1,1)*x+app.storeAll(k).regress(1,2),'--b');
                    hold(app.UIAxes, 'off');
                    app.storeGraph2 = str2double(app.storeAll(k).growthRank);
                    addStyle(app.UITable_2, s, 'row', app.storeGraph2);
                    
                    % Update information on the table below the graph
                    table = [app.storeAll(k).name app.storeAll(k).size app.storeAll(k).regress(1,1)];
                    app.UITable2.Data = table;
                end
            end
        end

        % Button pushed function: SaveImagesLabelsButton
        function SaveImagesLabelsButtonPushed(app, event)
            % Save image button has been pushed
            % Prevent images from being deleted
            app.saveImage = 1;
            
            % Move the folder to the selected directory
            imagePath = uigetdir;
            movefile('Images & Labels',imagePath,'f');
        end

        % Button pushed function: CloseAppButton
        function CloseAppButtonPushed(app, event)
            drawnow;
            if(~ishandle(app.f))
                close(app.f);
            end
            
            % If user didn't save images delete them
            if(app.saveImage ~= 1)
                rmdir('Images & Labels', 's');
            end
            
            % Delete current page
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Embryo Growth Over Time')
            xlabel(app.UIAxes, 'Time (h)')
            ylabel(app.UIAxes, 'Embryo Size (um^2)')
            app.PleasewaitLabel = uilabel(app.UIFigure);
            app.PleasewaitLabel.Text = 'Please wait...';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Video'; 'Rank'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Video'; 'Rank'};
            app.UITable_2.RowName = {};
            app.UITable_2.CellSelectionCallback = createCallbackFcn(app, @UITable_2CellSelection, true);
            app.FinalEmbryoSizeLabel = uilabel(app.UIFigure);
            app.FinalEmbryoSizeLabel.Text = 'Final Embryo Size';
            app.AverageGrowthRateLabel = uilabel(app.UIFigure);
            app.AverageGrowthRateLabel.Text = 'Average Growth Rate';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Video'; 'Final Size'; 'Average Growth Rate'};
            app.UITable2.RowName = {};
            app.Panel = uipanel(app.UIFigure);
            app.SaveresultsButton = uibutton(app.Panel, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Visible = 'off';
            app.SaveresultsButton.Text = 'Save results';
            app.LoadnewvideosButton = uibutton(app.Panel, 'push');
            app.LoadnewvideosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadnewvideosButtonPushed, true);
            app.LoadnewvideosButton.Text = 'Load new videos';
            app.SaveImagesLabelsButton = uibutton(app.Panel, 'push');
            app.SaveImagesLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagesLabelsButtonPushed, true);
            app.SaveImagesLabelsButton.Visible = 'off';
            app.SaveImagesLabelsButton.Text = 'Save Images & Labels';
            app.CloseAppButton = uibutton(app.Panel, 'push');
            app.CloseAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAppButtonPushed, true);
            app.CloseAppButton.Text = 'Close App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizeResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end